classdef ito45 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DifferenciaCheckBox  matlab.ui.control.CheckBox
        StartButton          matlab.ui.control.Button
        ItoPicture           matlab.ui.control.UIAxes
        TranszPicture        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartCallback(app, event)
            try
                n = 1000;m=100;
                [w,t]= wiener(n,m);
                f = @(x) x.^3;
                g = @(x) 3*x.^2;
                h = @(x) 6*x; %korrekciós tag
                E = f(w)-f(0);
                plot(app.TranszPicture,t,E);
                grid(app.TranszPicture,'on');
                I1 = stint(g(w),w);
                I2 = stint(h(w),kwar(w));
                I = I1 + 0.5*I2;
                if app.DifferenciaCheckBox.Value
                    plot(app.ItoPicture,t,E-I);
                else
                    plot(app.ItoPicture,t,I);
                end
                grid(app.ItoPicture,'on');
            catch ME
                msgbox(ME.message)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TranszPicture = uiaxes(app.UIFigure);
            title(app.TranszPicture, 'Transzformált')
            xlabel(app.TranszPicture, 'X')
            ylabel(app.TranszPicture, 'Y')
            zlabel(app.TranszPicture, 'Z')
            app.ItoPicture = uiaxes(app.UIFigure);
            title(app.ItoPicture, 'Ito felbontás')
            xlabel(app.ItoPicture, 'X')
            ylabel(app.ItoPicture, 'Y')
            zlabel(app.ItoPicture, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartCallback, true);
            app.StartButton.Text = 'Start';
            app.DifferenciaCheckBox = uicheckbox(app.UIFigure);
            app.DifferenciaCheckBox.Text = 'Differencia';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ito45

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end